classdef GUI_v2_01_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        axes1                      matlab.ui.control.UIAxes
        text7                      matlab.ui.control.Label
        panel_hierarchy            matlab.ui.container.ButtonGroup
        hierarchy_1                matlab.ui.control.RadioButton
        hierarchy_2                matlab.ui.control.RadioButton
        panel_type                 matlab.ui.container.ButtonGroup
        type_1                     matlab.ui.control.RadioButton
        type_2                     matlab.ui.control.RadioButton
        panel_epsilon_et           matlab.ui.container.Panel
        edit_eps_et_min            matlab.ui.control.EditField
        edit_eps_et_max            matlab.ui.control.EditField
        text26                     matlab.ui.control.Label
        text25                     matlab.ui.control.Label
        Eps_et                     matlab.ui.control.Button
        Inc                        matlab.ui.control.Label
        edit_eps_et_inc            matlab.ui.control.EditField
        panel_epsilon_tt           matlab.ui.container.Panel
        edit_eps_tt_min            matlab.ui.control.EditField
        edit_eps_tt_max            matlab.ui.control.EditField
        text31                     matlab.ui.control.Label
        text32                     matlab.ui.control.Label
        Eps_tt                     matlab.ui.control.Button
        text63                     matlab.ui.control.Label
        edit_eps_tt_inc            matlab.ui.control.EditField
        toogglebutton_hold_button  matlab.ui.control.StateButton
        panel_delta                matlab.ui.container.Panel
        edit_delta_NSI             matlab.ui.control.EditField
        edit_delta_dirac           matlab.ui.control.EditField
        text23                     matlab.ui.control.Label
        text22                     matlab.ui.control.Label
        text21                     matlab.ui.control.Label
        text24                     matlab.ui.control.Label
        panel_epsilon_ee           matlab.ui.container.Panel
        edit_eps_ee_min            matlab.ui.control.EditField
        edit_eps_ee_max            matlab.ui.control.EditField
        text29                     matlab.ui.control.Label
        Eps_ee                     matlab.ui.control.Button
        text30                     matlab.ui.control.Label
        text62                     matlab.ui.control.Label
        edit_eps_ee_inc            matlab.ui.control.EditField
        panel_distance             matlab.ui.container.Panel
        distance_box               matlab.ui.control.EditField
        text15                     matlab.ui.control.Label
        text98                     matlab.ui.control.Label
        panel_epsilons             matlab.ui.container.Panel
        eps_ee_nsi                 matlab.ui.control.EditField
        eps_et_nsi                 matlab.ui.control.EditField
        text94                     matlab.ui.control.Label
        text97                     matlab.ui.control.Label
        eps_tt_nsi                 matlab.ui.control.EditField
        text95                     matlab.ui.control.Label
        text101                    matlab.ui.control.Label
        text102                    matlab.ui.control.Label
        uipanel18                  matlab.ui.container.Panel
        button_minos_flux          matlab.ui.control.Button
        button_event_rate_nova     matlab.ui.control.Button
        text105                    matlab.ui.control.Label
        edit_det_eff               matlab.ui.control.EditField
        uipanel19                  matlab.ui.container.Panel
        M_E_NSI_Probability        matlab.ui.control.Button
    end

    
    % Properties for app components created in addRuntimeConfigurations
    properties (Access = public)
        uitoolbar1    matlab.ui.container.Toolbar
        uitoggletool1 matlab.ui.container.toolbar.ToggleTool
        uitoggletool3 matlab.ui.container.toolbar.ToggleTool
        uitoggletool4 matlab.ui.container.toolbar.ToggleTool
        uitoggletool5 matlab.ui.container.toolbar.ToggleTool
    end
    
    methods (Access = private)
        function mainFunc(app, hObject, handles)
            
            % Create waitbar
            wb = waitbar(0,'');
            
            % Create Axes
            axes(handles.axes1);
            
            % Create Legend
            handles.legend1 = legend(handles.axes1,'off');
            
            % Control Plot Color if Hold ON Pressed
            if handles.color_check == 1
                if handles.color_indice < handles.color_indice_max
                    handles.color_indice = handles.color_indice + 1;
                else
                    handles.color_indice = 1;
                end
            else
                handles.color_indice = 1;
            end
            
            % Plot Result with given parameters
            handles.check_par = Plot_Request(...
                handles,...
                wb);
            
            handles.legend1 = legend(handles.axes1,'show');
            set(handles.legend1,'Interpreter','latex','FontSize',12);
            
            
            % Close Waitbar
            close(wb);
            
            % Call GUI_Parameters Window
            GUI_Parameters(handles.check_par);
            
            % Update handles
            guidata(hObject,handles);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('GUI_v2_01_App.mat');
            
            % Create uitoolbar1
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            
            % Create uitoggletool1
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.ClickedCallback = 'insertmenufcn(gcbf,''Legend'')';
            app.uitoggletool1.CData = componentData.uitoggletool1.CData;
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Insert Legend';
            
            % Create uitoggletool3
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.ClickedCallback = 'putdowntext(''datatip'',gcbo)';
            app.uitoggletool3.CData = componentData.uitoggletool3.CData;
            app.uitoggletool3.Separator = 'on';
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Data Cursor';
            
            % Create uitoggletool4
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.ClickedCallback = 'putdowntext(''zoomin'',gcbo)';
            app.uitoggletool4.CData = componentData.uitoggletool4.CData;
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Zoom In';
            
            % Create uitoggletool5
            app.uitoggletool5 = uitoggletool(app.uitoolbar1);
            app.uitoggletool5.ClickedCallback = 'putdowntext(''zoomout'',gcbo)';
            app.uitoggletool5.CData = componentData.uitoggletool5.CData;
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Zoom Out';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_v2_01_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            handles.output = hObject;
            
            % Colors for the Plot
            handles.Plot_Color = {'b','r','g', 'k','c','m','y'};
            handles.color_indice = 1;
            handles.color_indice_max = 7;
            handles.color_check = 0;
            
            % Default Values used if user does not input another value
            hold off;
            
            % Distance
            handles.distance = 500;
            
            % Delta
            handles.dirac_delta = 0;
            handles.NSI_delta = 0;
            
            % Epsilon Range
            handles.eps_min = zeros(1,3);
            for i = 1:3
                handles.eps_min(i) = -0.2;
            end
            
            handles.eps_max = zeros(1,3);
            for i = 1:3
                handles.eps_max(i) = 0.2;
            end
            
            handles.eps_inc = zeros(1,3);
            for i = 1:3
                handles.eps_inc(i) = 0.1;
            end
            
            
            % Epsilon Single Value
            handles.eps_nsi = zeros(1,3);
            for i = 1:3
                handles.eps_nsi(i) = 0;
            end
            
            
            % Default hierarchy and type
            set(handles.panel_hierarchy, 'SelectedObject', handles.hierarchy_1);
            set(handles.panel_type, 'SelectedObject', handles.type_1);
            
            % Normal Hierarchy = 1 and Neutrino = 1
            handles.h1 = 1;
            handles.h2 = 0;
            
            handles.t1 = 1;
            handles.t2 = 0;
            
            % Experiment
            handles.experiment = 0;
            
            % Calculation Type
            handles.isEventRate = 0;
            
            % Plottin is for a Range or Single
            handles.isRange = 0;
            % epsilon_ee = 1
            % epsilon_et = 2
            % epsilon_tt = 3
            handles.rangeEps = 0;
            
            % Detector Efficiency
            handles.det_eff = 0.69;
            
            % Default Hold Button
            handles.hold_button_check = 0;
            
            % Read Flux Files Once when the GUI Starts
            % experiment = 1 for MINOS
            % experiment = 0 for NOVA
            [handles.Flux_Energy_MINOS,handles.n_nu_mu_10_MINOS] = Read_Flux_Files(1);
            [handles.Flux_Energy_NOVA,handles.n_nu_mu_10_NOVA] = Read_Flux_Files(0);
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes GUI_v2_01 wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
        end

        % Button pushed function: Eps_ee
        function Eps_ee_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Set isRange for Plotting
            handles.isRange = 1;
            handles.rangeEps = 1;
            
            % Set Event Rate and Experiment Parameters
            handles.isEventRate = 0;
            handles.experiment = 0;
            
            
            % Update handles
            guidata(hObject,handles);
            
            % Call Main Funct
            mainFunc(app, hObject,handles);
        end

        % Button pushed function: Eps_et
        function Eps_et_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Set isRange for Plotting
            handles.isRange = 1;
            handles.rangeEps = 2;
            
            % Set Event Rate and Experiment Parameters
            handles.isEventRate = 0;
            handles.experiment = 0;
            
            
            % Update handles
            guidata(hObject,handles);
            
            % Call Main Funct
            mainFunc(app, hObject,handles);
        end

        % Button pushed function: Eps_tt
        function Eps_tt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Set isRange for Plotting
            handles.isRange = 1;
            handles.rangeEps = 3;
            
            % Set Event Rate and Experiment Parameters
            handles.isEventRate = 0;
            handles.experiment = 0;
            
            
            % Update handles
            guidata(hObject,handles);
            
            % Call Main Funct
            mainFunc(app, hObject,handles);
        end

        % Button pushed function: M_E_NSI_Probability
        function M_E_NSI_Probability_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Set isRange for Plotting
            handles.isRange = 0;
            
            % Set Event Rate and Experiment Parameters
            handles.isEventRate = 0;
            handles.experiment = 0;
            
            % Update handles
            guidata(hObject,handles);
            
            
            % Call Main Funct
            mainFunc(app, hObject,handles);
        end

        % Button pushed function: button_event_rate_nova
        function button_event_rate_nova_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Set isRange for Plotting
            handles.isRange = 0;
            
            % Set Event Rate and Experiment Parameters
            handles.isEventRate = 1;
            handles.experiment = 0;
            
            % Update handles
            guidata(hObject,handles);
            
            % Call Main Funct
            mainFunc(app, hObject,handles);
        end

        % Button pushed function: button_minos_flux
        function button_minos_flux_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Set isRange for Plotting
            handles.isRange = 0;
            
            % Set Event Rate and Experiment Parameters
            handles.isEventRate = 1;
            handles.experiment = 1;
            
            % Update handles
            guidata(hObject,handles);
            
            % Call Main Funct
            mainFunc(app, hObject,handles);
        end

        % Value changed function: distance_box
        function distance_box_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                distance = str2double(get(hObject,'String'));
            
                if isnan(distance)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                if distance < 100
                    set(hObject, 'String', 0);
                    errordlg('Input must be higher than 100km','Error');
                end
            
                if distance > 12500
                    set(hObject, 'String', 0);
                    errordlg('Input must be lower than 12500km','Error');
                end
            
                handles.distance = distance;
            
                guidata(hObject,handles)
        end

        % Value changed function: edit_delta_NSI
        function edit_delta_NSI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                NSI_delta=str2double(get(hObject,'String'));
            
                if isnan(NSI_delta)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                % Dirac_Delta is in units of pi
                handles.NSI_delta = NSI_delta*pi;
                guidata(hObject,handles)
        end

        % Value changed function: edit_delta_dirac
        function edit_delta_dirac_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                dirac_delta=str2double(get(hObject,'String'));
            
                if isnan(dirac_delta)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                % Dirac_Delta is in units of pi
                handles.dirac_delta = dirac_delta * pi;
                guidata(hObject,handles)
        end

        % Value changed function: edit_det_eff
        function edit_det_eff_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                det_eff = str2double(get(hObject,'String'));
            
                if isnan(det_eff)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                if det_eff < 0
                    set(hObject, 'String', 0);
                    errordlg('Detector Efficiency can not be lower than 0','Error');
                end
            
                if det_eff > 1
                    set(hObject, 'String', 0);
                    errordlg('Detector Efficiency can not be higher than 1','Error');
                end
            
                handles.det_eff = det_eff;
            
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_ee_inc
        function edit_eps_ee_inc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_ee_inc=str2double(get(hObject,'String'));
                if isnan(eps_ee_inc)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_inc(1) = eps_ee_inc;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_ee_max
        function edit_eps_ee_max_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_ee_max=str2double(get(hObject,'String'));
                if isnan(eps_ee_max)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_max(1) = eps_ee_max;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_ee_min
        function edit_eps_ee_min_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_ee_min=str2double(get(hObject,'String'));
                if isnan(eps_ee_min)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_min(1) = eps_ee_min;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_et_inc
        function edit_eps_et_inc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_et_inc=str2double(get(hObject,'String'));
            
                if isnan(eps_et_inc)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_inc(2) = eps_et_inc;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_et_max
        function edit_eps_et_max_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_et_max=str2double(get(hObject,'String'));
            
                if isnan(eps_et_max)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_max(2) = eps_et_max;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_et_min
        function edit_eps_et_min_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_et_min=str2double(get(hObject,'String'));
            
                if isnan(eps_et_min)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_min(2) = eps_et_min;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_tt_inc
        function edit_eps_tt_inc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_tt_inc=str2double(get(hObject,'String'));
                if isnan(eps_tt_inc)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_inc(3) = eps_tt_inc;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_tt_max
        function edit_eps_tt_max_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_tt_max=str2double(get(hObject,'String'));
                if isnan(eps_tt_max)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_max(3) = eps_tt_max;
                guidata(hObject,handles)
        end

        % Value changed function: edit_eps_tt_min
        function edit_eps_tt_min_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_tt_min=str2double(get(hObject,'String'));
                if isnan(eps_tt_min)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_min(3) = eps_tt_min;
                guidata(hObject,handles)
        end

        % Value changed function: eps_ee_nsi
        function eps_ee_nsi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_ee_nsi=str2double(get(hObject,'String'));
                if isnan(eps_ee_nsi)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_nsi(1) = eps_ee_nsi;
                guidata(hObject,handles)
        end

        % Value changed function: eps_et_nsi
        function eps_et_nsi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_et_nsi=str2double(get(hObject,'String'));
                if isnan(eps_et_nsi)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_nsi(2) = eps_et_nsi;
                guidata(hObject,handles)
        end

        % Value changed function: eps_tt_nsi
        function eps_tt_nsi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                eps_tt_nsi=str2double(get(hObject,'String'));
                if isnan(eps_tt_nsi)
                    set(hObject, 'String', 0);
                    errordlg('Input must be a number','Error');
                end
            
                handles.eps_nsi(3) = eps_tt_nsi;
                guidata(hObject,handles)
        end

        % Selection changed function: panel_hierarchy
        function panel_hierarchy_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                if (hObject == handles.hierarchy_1)
                    handles.h1 = 1;
                    handles.h2 = 0;
                    guidata(hObject,handles)
                else
                    handles.h1 = 0;
                    handles.h2 = 1;
                    guidata(hObject,handles)
                end
        end

        % Selection changed function: panel_type
        function panel_type_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                if (hObject == handles.type_1)
                    handles.t1=1;
                    handles.t2=0;
                    guidata(hObject,handles)
                else
                    handles.t1=0;
                    handles.t2=1;
                    guidata(hObject,handles)
                end
        end

        % Value changed function: toogglebutton_hold_button
        function toogglebutton_hold_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Hint: get(hObject,'Value') returns toggle state of toogglebutton_hold_button
            
            button_state = get(hObject,'Value');
            
            if button_state == get(hObject,'Max')
                % Toggle button is pressed, take appropriate action
                hold on
                handles.color_check = 1;
            
            elseif button_state == get(hObject,'Min')
                % Toggle button is not pressed, take appropriate action
                hold off
                handles.color_check = 0;
            end
            
            guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_v2_01';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0.7 10];
            app.axes1.Tag = 'axes1';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Neutrino Oscillations Toolbox';
            app.panel_hierarchy = uibuttongroup(app.figure1);
            app.panel_hierarchy.SelectionChangedFcn = createCallbackFcn(app, @panel_hierarchy_SelectionChangeFcn, true);
            app.panel_hierarchy.Title = 'Hierarchy';
            app.panel_hierarchy.Tag = 'panel_hierarchy';
            app.hierarchy_1 = uiradiobutton(app.panel_hierarchy);
            app.hierarchy_1.Tag = 'hierarchy_1';
            app.hierarchy_1.Text = 'Normal';
            app.hierarchy_1.Value = true;
            app.hierarchy_2 = uiradiobutton(app.panel_hierarchy);
            app.hierarchy_2.Tag = 'hierarchy_2';
            app.hierarchy_2.Text = 'Inverted';
            app.panel_type = uibuttongroup(app.figure1);
            app.panel_type.SelectionChangedFcn = createCallbackFcn(app, @panel_type_SelectionChangeFcn, true);
            app.panel_type.Title = 'Type';
            app.panel_type.Tag = 'panel_type';
            app.type_1 = uiradiobutton(app.panel_type);
            app.type_1.Tag = 'type_1';
            app.type_1.Text = 'Neutrino';
            app.type_1.Value = true;
            app.type_2 = uiradiobutton(app.panel_type);
            app.type_2.Tag = 'type_2';
            app.type_2.Text = 'Anti-Neutrino';
            app.panel_epsilon_et = uipanel(app.figure1);
            app.panel_epsilon_et.Title = 'Epsilon(et) [-0.2,0.2]';
            app.panel_epsilon_et.Tag = 'panel_epsilon_et';
            app.edit_eps_et_min = uieditfield(app.panel_epsilon_et, 'text');
            app.edit_eps_et_min.ValueChangedFcn = createCallbackFcn(app, @edit_eps_et_min_Callback, true);
            app.edit_eps_et_min.Tag = 'edit_eps_et_min';
            app.edit_eps_et_min.Value = '-0.2';
            app.edit_eps_et_max = uieditfield(app.panel_epsilon_et, 'text');
            app.edit_eps_et_max.ValueChangedFcn = createCallbackFcn(app, @edit_eps_et_max_Callback, true);
            app.edit_eps_et_max.Tag = 'edit_eps_et_max';
            app.edit_eps_et_max.Value = '0.2';
            app.text26 = uilabel(app.panel_epsilon_et);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Max';
            app.text25 = uilabel(app.panel_epsilon_et);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Min';
            app.Eps_et = uibutton(app.panel_epsilon_et, 'push');
            app.Eps_et.ButtonPushedFcn = createCallbackFcn(app, @Eps_et_Callback, true);
            app.Eps_et.Tag = 'Eps_et';
            app.Eps_et.Text = 'Plot';
            app.Inc = uilabel(app.panel_epsilon_et);
            app.Inc.Tag = 'Inc';
            app.Inc.Text = 'Inc';
            app.edit_eps_et_inc = uieditfield(app.panel_epsilon_et, 'text');
            app.edit_eps_et_inc.ValueChangedFcn = createCallbackFcn(app, @edit_eps_et_inc_Callback, true);
            app.edit_eps_et_inc.Tag = 'edit_eps_et_inc';
            app.edit_eps_et_inc.Value = '0.1';
            app.panel_epsilon_tt = uipanel(app.figure1);
            app.panel_epsilon_tt.Title = 'Epsilon(tt) [-0.2,0.2]';
            app.panel_epsilon_tt.Tag = 'panel_epsilon_tt';
            app.edit_eps_tt_min = uieditfield(app.panel_epsilon_tt, 'text');
            app.edit_eps_tt_min.ValueChangedFcn = createCallbackFcn(app, @edit_eps_tt_min_Callback, true);
            app.edit_eps_tt_min.Tag = 'edit_eps_tt_min';
            app.edit_eps_tt_min.Value = '-0.2';
            app.edit_eps_tt_max = uieditfield(app.panel_epsilon_tt, 'text');
            app.edit_eps_tt_max.ValueChangedFcn = createCallbackFcn(app, @edit_eps_tt_max_Callback, true);
            app.edit_eps_tt_max.Tag = 'edit_eps_tt_max';
            app.edit_eps_tt_max.Value = '0.2';
            app.text31 = uilabel(app.panel_epsilon_tt);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Max';
            app.text32 = uilabel(app.panel_epsilon_tt);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Min';
            app.Eps_tt = uibutton(app.panel_epsilon_tt, 'push');
            app.Eps_tt.ButtonPushedFcn = createCallbackFcn(app, @Eps_tt_Callback, true);
            app.Eps_tt.Tag = 'Eps_tt';
            app.Eps_tt.Text = 'Plot';
            app.text63 = uilabel(app.panel_epsilon_tt);
            app.text63.Tag = 'text63';
            app.text63.Text = 'Inc';
            app.edit_eps_tt_inc = uieditfield(app.panel_epsilon_tt, 'text');
            app.edit_eps_tt_inc.ValueChangedFcn = createCallbackFcn(app, @edit_eps_tt_inc_Callback, true);
            app.edit_eps_tt_inc.Tag = 'edit_eps_tt_inc';
            app.edit_eps_tt_inc.Value = '0.1';
            app.toogglebutton_hold_button = uibutton(app.figure1, 'state');
            app.toogglebutton_hold_button.ValueChangedFcn = createCallbackFcn(app, @toogglebutton_hold_button_Callback, true);
            app.toogglebutton_hold_button.Tag = 'toogglebutton_hold_button';
            app.toogglebutton_hold_button.Text = 'Hold On';
            app.panel_delta = uipanel(app.figure1);
            app.panel_delta.Title = 'Delta [0,2]';
            app.panel_delta.Tag = 'panel_delta';
            app.edit_delta_NSI = uieditfield(app.panel_delta, 'text');
            app.edit_delta_NSI.ValueChangedFcn = createCallbackFcn(app, @edit_delta_NSI_Callback, true);
            app.edit_delta_NSI.Tag = 'edit_delta_NSI';
            app.edit_delta_NSI.Value = '0';
            app.edit_delta_dirac = uieditfield(app.panel_delta, 'text');
            app.edit_delta_dirac.ValueChangedFcn = createCallbackFcn(app, @edit_delta_dirac_Callback, true);
            app.edit_delta_dirac.Tag = 'edit_delta_dirac';
            app.edit_delta_dirac.Value = '0';
            app.text23 = uilabel(app.panel_delta);
            app.text23.Tag = 'text23';
            app.text23.Text = 'p';
            app.text22 = uilabel(app.panel_delta);
            app.text22.Tag = 'text22';
            app.text22.Text = 'NSI';
            app.text21 = uilabel(app.panel_delta);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Dirac';
            app.text24 = uilabel(app.panel_delta);
            app.text24.Tag = 'text24';
            app.text24.Text = 'p';
            app.panel_epsilon_ee = uipanel(app.figure1);
            app.panel_epsilon_ee.Title = 'Epsilon(ee) [-0.2,0.2]';
            app.panel_epsilon_ee.Tag = 'panel_epsilon_ee';
            app.edit_eps_ee_min = uieditfield(app.panel_epsilon_ee, 'text');
            app.edit_eps_ee_min.ValueChangedFcn = createCallbackFcn(app, @edit_eps_ee_min_Callback, true);
            app.edit_eps_ee_min.Tag = 'edit_eps_ee_min';
            app.edit_eps_ee_min.Value = '-0.2';
            app.edit_eps_ee_max = uieditfield(app.panel_epsilon_ee, 'text');
            app.edit_eps_ee_max.ValueChangedFcn = createCallbackFcn(app, @edit_eps_ee_max_Callback, true);
            app.edit_eps_ee_max.Tag = 'edit_eps_ee_max';
            app.edit_eps_ee_max.Value = '0.2';
            app.text29 = uilabel(app.panel_epsilon_ee);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Max';
            app.Eps_ee = uibutton(app.panel_epsilon_ee, 'push');
            app.Eps_ee.ButtonPushedFcn = createCallbackFcn(app, @Eps_ee_Callback, true);
            app.Eps_ee.Tag = 'Eps_ee';
            app.Eps_ee.Text = 'Plot';
            app.text30 = uilabel(app.panel_epsilon_ee);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Min';
            app.text62 = uilabel(app.panel_epsilon_ee);
            app.text62.Tag = 'text62';
            app.text62.Text = 'Inc';
            app.edit_eps_ee_inc = uieditfield(app.panel_epsilon_ee, 'text');
            app.edit_eps_ee_inc.ValueChangedFcn = createCallbackFcn(app, @edit_eps_ee_inc_Callback, true);
            app.edit_eps_ee_inc.Tag = 'edit_eps_ee_inc';
            app.edit_eps_ee_inc.Value = '0.1';
            app.panel_distance = uipanel(app.figure1);
            app.panel_distance.Title = 'Distance';
            app.panel_distance.Tag = 'panel_distance';
            app.distance_box = uieditfield(app.panel_distance, 'text');
            app.distance_box.ValueChangedFcn = createCallbackFcn(app, @distance_box_Callback, true);
            app.distance_box.Tag = 'distance_box';
            app.distance_box.Value = '500';
            app.text15 = uilabel(app.panel_distance);
            app.text15.Tag = 'text15';
            app.text15.Text = 'km';
            app.text98 = uilabel(app.panel_distance);
            app.text98.Tag = 'text98';
            app.text98.Text = '[100,12500]';
            app.panel_epsilons = uipanel(app.figure1);
            app.panel_epsilons.Title = 'NSI Epsilon';
            app.panel_epsilons.Tag = 'panel_epsilons';
            app.eps_ee_nsi = uieditfield(app.panel_epsilons, 'text');
            app.eps_ee_nsi.ValueChangedFcn = createCallbackFcn(app, @eps_ee_nsi_Callback, true);
            app.eps_ee_nsi.Tag = 'eps_ee_nsi';
            app.eps_ee_nsi.Value = '0';
            app.eps_et_nsi = uieditfield(app.panel_epsilons, 'text');
            app.eps_et_nsi.ValueChangedFcn = createCallbackFcn(app, @eps_et_nsi_Callback, true);
            app.eps_et_nsi.Tag = 'eps_et_nsi';
            app.eps_et_nsi.Value = '0';
            app.text94 = uilabel(app.panel_epsilons);
            app.text94.Tag = 'text94';
            app.text94.Text = 'ee';
            app.text97 = uilabel(app.panel_epsilons);
            app.text97.Tag = 'text97';
            app.text97.Text = 'tt';
            app.eps_tt_nsi = uieditfield(app.panel_epsilons, 'text');
            app.eps_tt_nsi.ValueChangedFcn = createCallbackFcn(app, @eps_tt_nsi_Callback, true);
            app.eps_tt_nsi.Tag = 'eps_tt_nsi';
            app.eps_tt_nsi.Value = '0';
            app.text95 = uilabel(app.panel_epsilons);
            app.text95.Tag = 'text95';
            app.text95.Text = 'et';
            app.text101 = uilabel(app.figure1);
            app.text101.Tag = 'text101';
            app.text101.Text = 'Probability: Muon-Electron';
            app.text102 = uilabel(app.figure1);
            app.text102.Tag = 'text102';
            app.text102.Text = 'Epsilon Variation';
            app.uipanel18 = uipanel(app.figure1);
            app.uipanel18.Title = 'Event Rate';
            app.uipanel18.Tag = 'uipanel18';
            app.button_minos_flux = uibutton(app.uipanel18, 'push');
            app.button_minos_flux.ButtonPushedFcn = createCallbackFcn(app, @button_minos_flux_Callback, true);
            app.button_minos_flux.Tag = 'button_minos_flux';
            app.button_minos_flux.Text = 'MINOS';
            app.button_event_rate_nova = uibutton(app.uipanel18, 'push');
            app.button_event_rate_nova.ButtonPushedFcn = createCallbackFcn(app, @button_event_rate_nova_Callback, true);
            app.button_event_rate_nova.Tag = 'button_event_rate_nova';
            app.button_event_rate_nova.Text = 'NOVA';
            app.text105 = uilabel(app.uipanel18);
            app.text105.Tag = 'text105';
            app.text105.Text = 'Detector Efficiency';
            app.edit_det_eff = uieditfield(app.uipanel18, 'text');
            app.edit_det_eff.ValueChangedFcn = createCallbackFcn(app, @edit_det_eff_Callback, true);
            app.edit_det_eff.Tag = 'edit_det_eff';
            app.edit_det_eff.Value = '0.69';
            app.uipanel19 = uipanel(app.figure1);
            app.uipanel19.Title = 'Probability';
            app.uipanel19.Tag = 'uipanel19';
            app.M_E_NSI_Probability = uibutton(app.uipanel19, 'push');
            app.M_E_NSI_Probability.ButtonPushedFcn = createCallbackFcn(app, @M_E_NSI_Probability_Callback, true);
            app.M_E_NSI_Probability.Tag = 'M_E_NSI_Probability';
            app.M_E_NSI_Probability.Text = 'Muon - Electron';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v2_01_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)GUI_v2_01_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end