classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Neutrino3flavorOscillationswithDecoherenceLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Muon_ElectronButton             matlab.ui.control.Button
        DistancekmEditFieldLabel        matlab.ui.control.Label
        DistancekmEditField             matlab.ui.control.NumericEditField
        DiracPhaseLabel                 matlab.ui.control.Label
        DiracPhaseEditField             matlab.ui.control.NumericEditField
        HierarchySwitchLabel            matlab.ui.control.Label
        HierarchySwitch                 matlab.ui.control.Switch
        Theta23EditFieldLabel           matlab.ui.control.Label
        Theta23EditField                matlab.ui.control.NumericEditField
        Deltam31SqEditFieldLabel        matlab.ui.control.Label
        Deltam31SqEditField             matlab.ui.control.NumericEditField
        DecoherenceGammaEditFieldLabel  matlab.ui.control.Label
        DecoherenceGammaEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
       
        function DeclareConstants(app)
            global theta_13;
            global theta_12;
            global DELTA_m21_sq;
            global Ve;
            
            % theta13 from Daya Bay Result
            sinsq_2theta_13 = 0.092;                    % [degrees] 
            sin_2theta_13 = sqrt(sinsq_2theta_13);
            theta_13 = asin(sin_2theta_13)./2;          % [radians]

            % theta 12
            theta_12_degree = 34.45;                    % [degrees]
            theta_12 = theta_12_degree .* pi./180;      % [radians]

            % Mass Eigenstate Differences
            DELTA_m21_sq = 7.54e-5;
            
            % Matter Potential
            Ve = 1.0 / 1900.0;
        end
        
        function GetParametersFromGUI(app)
            global distance;
            global diracPhase;
            global theta_23;
            global DELTA_m31_sq;
            global GL;
            
            distance = app.DistancekmEditField.Value;
            
            diracPhase = app.DiracPhaseEditField.Value;
            
            theta_23_degree = app.Theta23EditField.Value;
            theta_23 = theta_23_degree .* pi./180;
            
            DELTA_m31_sq = app.Deltam31SqEditField.Value;
            
            gamma0 = app.DecoherenceGammaEditField.Value;
            gamma = gamma0 ./ 19750.0;
            GL = gamma .* distance;
        end
        
        function [Energy, Probability] = CalculateMuonElectronProbability(app)
            global theta_13;
            global theta_12;
            global DELTA_m21_sq;
            global distance;
            global diracPhase;
            global theta_23;
            global DELTA_m31_sq;
            global GL;
            global Ve;
            
            % Define the Neutrino Energy (Ranging from [0.4,10] GeV)
            Energy = linspace(0.4, 10, 1000);

            % Define the effective Matter Potential
            A = 0.5 .* (Ve .* Energy) ./ (1.27 .* DELTA_m31_sq);
            
            % Define the Oscillation Phase
            oscPhase = (1.27 .* DELTA_m31_sq .* distance) ./ Energy;
            
            % Calculate Sine & Cosine of Mixing Angles for Probability
            % theta_13
            sin_theta_13 = sin(theta_13);
            sinSq_2theta_13 = sin(2 * theta_13).^2;
            
            % theta_23
            sinSq_theta_23 = sin(theta_23).^2;
            cosSq_theta_23 = cos(theta_23).^2;
            sin_2theta_23 = sin(2*theta_23);
            
            % theta_12
            sin_2theta_12 = sin(2*theta_12);
            sinSq_2theta_12 = sin_2theta_12.^2;
            
            % Calculate Probability Terms
            P0 =  (sinSq_2theta_13 * sinSq_theta_23) ./ (2 .* ((A - 1) .* A).^2) .* (1 - (exp(-GL) .* cos((1-A) .* 2 .* oscPhase)));
            
            Probability = P0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % -------------------------------------------------------------
            % Modify Text with Special Characters
            % -------------------------------------------------------------
            app.DistancekmEditFieldLabel.Text = 'Distance [km]';
            app.Theta23EditFieldLabel.Text = [char(952), char(8322), char(8323), ' [degrees]'];
            app.Deltam31SqEditFieldLabel.Text = [char(916), 'm', char(178), char(8323), char(8321), ' [', '10', char(8315), char(179), ' eV', char(178),']'];
            app.DiracPhaseLabel.Text = ['Dirac Phase (', char(948), ')', ' [', char(960) ']' ];
            app.DecoherenceGammaEditFieldLabel.Text = ['Decoherence (', char(915), char(8320), ')'];
            app.Muon_ElectronButton.Text = ['Muon ', char(8594), ' Electron'];
            
            DeclareConstants(app);
        end

        % Button pushed function: Muon_ElectronButton
        function Muon_ElectronButtonPushed(app, event)

            GetParametersFromGUI(app)

            [Energy, Probability] = CalculateMuonElectronProbability(app);
            
            plot(Energy,Probability,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Neutrino3flavorOscillationswithDecoherenceLabel = uilabel(app.UIFigure);
            app.Neutrino3flavorOscillationswithDecoherenceLabel.Text = 'Neutrino 3-flavor Oscillations with Decoherence';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Neutrino Energy (GeV)')
            ylabel(app.UIAxes, 'Probability')
            app.Muon_ElectronButton = uibutton(app.UIFigure, 'push');
            app.Muon_ElectronButton.ButtonPushedFcn = createCallbackFcn(app, @Muon_ElectronButtonPushed, true);
            app.Muon_ElectronButton.Text = 'Muon_Electron';
            app.DistancekmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancekmEditFieldLabel.Text = 'Distance (km)';
            app.DistancekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancekmEditField.Limits = [100 12800];
            app.DistancekmEditField.Value = 810;
            app.DiracPhaseLabel = uilabel(app.UIFigure);
            app.DiracPhaseLabel.Text = 'Dirac Phase ';
            app.DiracPhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.HierarchySwitchLabel = uilabel(app.UIFigure);
            app.HierarchySwitchLabel.Text = 'Hierarchy';
            app.HierarchySwitch = uiswitch(app.UIFigure, 'slider');
            app.HierarchySwitch.Items = {'Normal', 'Inverted'};
            app.HierarchySwitch.Value = 'Normal';
            app.Theta23EditFieldLabel = uilabel(app.UIFigure);
            app.Theta23EditFieldLabel.Text = 'Theta 23';
            app.Theta23EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta23EditField.Limits = [20 70];
            app.Theta23EditField.Value = 45;
            app.Deltam31SqEditFieldLabel = uilabel(app.UIFigure);
            app.Deltam31SqEditFieldLabel.Text = 'Delta m31 Sq';
            app.Deltam31SqEditField = uieditfield(app.UIFigure, 'numeric');
            app.Deltam31SqEditField.Limits = [2 3];
            app.Deltam31SqEditField.Value = 2;
            app.DecoherenceGammaEditFieldLabel = uilabel(app.UIFigure);
            app.DecoherenceGammaEditFieldLabel.Text = 'Decoherence Gamma';
            app.DecoherenceGammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecoherenceGammaEditField.Limits = [0.1 5];
            app.DecoherenceGammaEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end