classdef NeutrinoOscillationsUltimate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Neutrino3flavorOscillationswithDecoherenceLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PlotProbabilityPanel            matlab.ui.container.Panel
        Muon_ElectronButton             matlab.ui.control.Button
        Muon_Electron_SMButton          matlab.ui.control.Button
        Muon_MuonButton                 matlab.ui.control.Button
        ModifyParametersPanel           matlab.ui.container.Panel
        DistancekmEditFieldLabel        matlab.ui.control.Label
        DistancekmEditField             matlab.ui.control.NumericEditField
        DiracPhaseLabel                 matlab.ui.control.Label
        DiracPhaseEditField             matlab.ui.control.NumericEditField
        Theta23EditFieldLabel           matlab.ui.control.Label
        Theta23EditField                matlab.ui.control.NumericEditField
        Deltam31SqEditFieldLabel        matlab.ui.control.Label
        Deltam31SqEditField             matlab.ui.control.NumericEditField
        DecoherenceGammaEditFieldLabel  matlab.ui.control.Label
        DecoherenceGammaEditField       matlab.ui.control.NumericEditField
        SelectModePanel                 matlab.ui.container.Panel
        TypeSwitchLabel                 matlab.ui.control.Label
        TypeSwitch                      matlab.ui.control.Switch
        HierarchySwitchLabel            matlab.ui.control.Label
        HierarchySwitch                 matlab.ui.control.Switch
        ParametersUsedinCalculationPanel  matlab.ui.container.Panel
        ParametersUsedinCalculationTextArea  matlab.ui.control.TextArea
        SelectNeutrinoEnergyButtonGroup  matlab.ui.container.ButtonGroup
        GeVButton                       matlab.ui.control.RadioButton
        GeVButton_2                     matlab.ui.control.RadioButton
        GeVButton_3                     matlab.ui.control.RadioButton
        SelectPlotOptionsPanel          matlab.ui.container.Panel
        DecomposeButton                 matlab.ui.control.StateButton
        HoldOnButton                    matlab.ui.control.StateButton
        ClearPlotButton                 matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function rho = GetDensity(app, truel)
            %% This function calculates the earth density for the corresponding distance
            % Current function is a crude estimate of Earth's Density
            % Function name reserved for a better Density estimate Function
            
            pathl=[100, 1500, 3500, 10600, 11200, 12800];
            rhol=[2.3, 3.2, 3.35, 5.0, 6.3, 8.6];
            
            for i=2:6
                if((truel >= pathl(i-1)) && (truel <= pathl(i)))
                    path_dif=pathl(i)-pathl(i-1);
                    rho_dif=rhol(i)-rhol(i-1);
                    scale_factor = (truel-pathl(i-1)) / path_dif;
                    avg_rho=rhol(i-1)+scale_factor*rho_dif;
                end
            end
            
            % Scale the density by dividing with 2.72
            rho=avg_rho/2.72;
        end
        
        function DeclareConstants(app)
            global theta_13;
            global theta_12;
            global DELTA_m21_sq;
            global Ve;
            
            % theta13 
            theta_13_degree = 8.61;                             % [degrees] 
            theta_13 = deg2rad(theta_13_degree);                % [radians]
            
            % theta 12
            theta_12_degree = 33.82;                            % [degrees]
            theta_12 = deg2rad(theta_12_degree);                % [radians]
            
            % Mass Eigenstate Differences
            DELTA_m21_sq = 7.54e-5;
            
            % Matter Potential
            Ve = 1.0 / 1900.0;
        end
        
        function GetParametersFromGUI(app)
            global diracPhase;
            global theta_23;
            global DELTA_m21_sq;
            global DELTA_m31_sq;
            global GL;
            global rho;
            global alpha;
            global A;
            global oscPhase;
            global Ve;
            global Energy;
            global distance;

            % -------------------------------------------------------------
            % Get Parameters from GUI
            % -------------------------------------------------------------
            distance = app.DistancekmEditField.Value;          % [km]
            
            diracPhase = app.DiracPhaseEditField.Value;        % [pi]
            
            theta_23_degree = app.Theta23EditField.Value;      % [degrees]
            theta_23 = deg2rad(theta_23_degree);               % [radians]
            
            DELTA_m31_sq = app.Deltam31SqEditField.Value / 1000;
            
            gamma0 = app.DecoherenceGammaEditField.Value;
            gamma = gamma0 / 19750.0;
            GL = gamma .* distance;
            
            % Neutrino Energy (Ranging from [0.5,10] GeV)
            switch app.SelectNeutrinoEnergyButtonGroup.SelectedObject.Text
                case '3 GeV'
                    maxEnergy = 3;
                case '5 GeV'
                    maxEnergy = 5;
                case '10 GeV'
                    maxEnergy = 10;
            end
            Energy = linspace(0.5, maxEnergy, 1000);
            
            ApplyHierarchySelection(app);
            
            ApplyTypeSelection(app);
            
            % -------------------------------------------------------------
            % Calculate Helper Parameters
            % -------------------------------------------------------------
            % Density;
            rho = GetDensity(app, distance);
            
            % Alpha
            alpha = DELTA_m21_sq / DELTA_m31_sq;
            
            % Effective Matter Potential
            A = 0.5 .* (Ve .* Energy .* rho) ./ (1.27 .* DELTA_m31_sq);
            
            % Oscillation Phase
            oscPhase = (1.27 .* DELTA_m31_sq .* distance) ./ Energy;
        end
        
        function ApplyHierarchySelection(app)
            global DELTA_m31_sq;
            
            hierarchy = app.HierarchySwitch.Value;
            if(strcmp('Inverted', hierarchy))
                DELTA_m31_sq = DELTA_m31_sq * -1.0;
            end
        end
        
        function ApplyTypeSelection(app)
            global Ve;
            
            type = app.TypeSwitch.Value;
            if(strcmp('Anti-Neutrino', type))
                Ve = Ve * -1.0;
            end
        end
        
        function [ProbabilityTerms, plotLegend] = CalculateMuonElectronProbability(app)
            global theta_13;
            global theta_12;
            global theta_23;
            global DELTA_m21_sq;
            global DELTA_m31_sq;
            global diracPhase;
            global GL;
            global Ve;
            global rho;
            global alpha;
            global A;
            global oscPhase;

            % Calculate Sine-Cosine of Mixing Angles
            sin_theta_13 = sin(theta_13);
            sinSq_2theta_13 = sin(2.*theta_13).^2;
            
            sinSq_theta_23 = sin(theta_23).^2;
            cosSq_theta_23 = cos(theta_23).^2;
            sin_2theta_23 = sin(2.*theta_23);
            
            sin_2theta_12 = sin(2.*theta_12);
            sinSq_2theta_12 = sin_2theta_12.^2;
            
            % Calculate Probability Terms
            P0 =  (sinSq_2theta_13 .* sinSq_theta_23) ./ (2 .* ((A-1).^2)) .* (1 - (exp(-GL) .* cos((1-A).*2.*oscPhase)));
            
            Pcos_delta_1 = (alpha .* cos(diracPhase) .* sin_2theta_12 .* sin_theta_13 .* sin_2theta_23) ./ (2 .* (A-1) .* A);
            Pcos_delta_2 = (1 - cos(A.*2.*oscPhase) - exp(-GL) .* cos((1-A).*2.*oscPhase) + exp(-GL) .* cos(2.*oscPhase));
            Pcos_delta = Pcos_delta_1 .* Pcos_delta_2;
            
            P3 = (alpha.^2 .* cosSq_theta_23 .* sinSq_2theta_12) ./ (2 .* A.^2) .* (1-cos(A.*2.*oscPhase));
            
            Psin_delta_1 = (alpha .* sin(diracPhase) .* sin_2theta_12 .* sin_theta_13 .* sin_2theta_23) ./ (2 .* (1-A) .* A);
            Psin_delta_2 = (-sin(A.*2.*oscPhase) - exp(-GL) .* sin((1-A).*2.*oscPhase) + exp(-GL) .* sin(2.*oscPhase));
            Psin_delta = -1 .* (Psin_delta_1 .* Psin_delta_2);
            
            ProbabilityTerms = [P0; Pcos_delta; Psin_delta; P3];
            
            type = app.TypeSwitch.Value;
            hierarchy = app.HierarchySwitch.Value;
            plotLegend = sprintf('%s Appearance, %s', type, hierarchy);
            
            app.ParametersUsedinCalculationTextArea.Value = {...
                sprintf('theta_13 = %f degrees', rad2deg(theta_13)), ...
                sprintf('theta_23 = %f degrees', rad2deg(theta_23)), ...
                sprintf('theta_12 = %f degrees', rad2deg(theta_12)), ...
                sprintf('DELTA_m21_sq = %f', DELTA_m21_sq), ...
                sprintf('DELTA_m31_sq = %f', DELTA_m31_sq), ...
                sprintf('Ve = %f', Ve), ...
                sprintf('Density = %f', rho), ...
                sprintf('GL = %f', GL)};
        end
        function [ProbabilityTerms, plotLegend] = CalculateMuonElectronProbabilitySM(app)
            global theta_13;
            global theta_12;
            global theta_23;
            global DELTA_m21_sq;
            global DELTA_m31_sq;
            global diracPhase;
            global GL;
            global Ve;
            global rho;
            global alpha;
            global A;
            global oscPhase;
            
            % Calculate Sine-Cosine of Mixing Angles
            sin_2theta_13 = sin(2.*theta_13);
            sinSq_2theta_13 = sin_2theta_13.^2;

            sin_2theta_23 = sin(2.*theta_23);
            sinSq_theta_23 = sin(theta_23).^2;
            cosSq_theta_23 = cos(theta_23).^2;
            
            sin_2theta_12 = sin(2.*theta_12);
            sinSq_2theta_12 = sin_2theta_12.^2;
            
            % Calculate Probability Terms
            P0 = (sinSq_2theta_13 .* sinSq_theta_23) ./ (1-A).^2 .* (sin((1-A).*oscPhase).^2);
            
            X1 = alpha .* sin_2theta_13 .* cos(theta_13) .* sin_2theta_12 .* sin_2theta_23;
            X2 = (sin(A .* oscPhase).*sin((1-A).*oscPhase)) ./ (A.*(1-A));
            
            Pcos_delta = X1 .* X2 .* cos(diracPhase) .* cos(oscPhase);
            Psin_delta = X1 .* X2 .* sin(diracPhase) .* sin(oscPhase);
            
            P3 = (alpha.^2) .* cosSq_theta_23 .* sinSq_2theta_12 .* (sin(A.*oscPhase).^2) ./ (A.^2);
            
            ProbabilityTerms = [P0; Pcos_delta; Psin_delta; P3];
            
            type = app.TypeSwitch.Value;
            hierarchy = app.HierarchySwitch.Value;
            plotLegend = sprintf('SM, %s Appearance, %s', type, hierarchy);
            
            app.ParametersUsedinCalculationTextArea.Value = {...
                sprintf('theta_13 = %f degrees', rad2deg(theta_13)), ...
                sprintf('theta_23 = %f degrees', rad2deg(theta_23)), ...
                sprintf('theta_12 = %f degrees', rad2deg(theta_12)), ...
                sprintf('DELTA_m21_sq = %f', DELTA_m21_sq), ...
                sprintf('DELTA_m31_sq = %f', DELTA_m31_sq), ...
                sprintf('Ve = %f', Ve), ...
                sprintf('Density = %f', rho), ...
                sprintf('GL = %f', GL)};
        end
        
        function [Probability, plotLegend] = CalculateMuonMuonProbability(app)
            global theta_13;
            global theta_12;
            global theta_23;
            global DELTA_m21_sq;
            global DELTA_m31_sq;
            global GL;
            global Ve;
            global rho;
            global A;
            global Energy;
            global distance;
            
            % Calculate Sine-Cosine of Mixing Angles
            sin_2theta_13 = sin(2.*theta_13);
            sinSq_2theta_13 = sin_2theta_13.^2;
            cos_2theta_13 = cos(2.*theta_13);
            
            sinSq_2theta_23 = sin(2.*theta_23).^2;
            sinSq_theta_23 = sin(theta_23).^2;
            
            % Calculate Matter-Effective Mixing Angles
            tan_2theta_13_M = sin_2theta_13 ./ (cos_2theta_13 - A);
            
            cosSq_2theta_13_M = 1 ./ (tan_2theta_13_M.^2 + 1.0);
            sinSq_2theta_13_M = 1 - cosSq_2theta_13_M;
            cos_2theta_13_M = sqrt(cosSq_2theta_13_M);
            
            cosSq_theta_13_M = 0.5 .* (cos_2theta_13_M + 1.0);
            sinSq_theta_13_M = 1 - cosSq_theta_13_M;
                        
            % Calculate Matter-Effective Mass-Squared
            DELTA_Mm_Sq = DELTA_m31_sq .* sqrt((cos_2theta_13 - A).^2 + sinSq_2theta_13);
            
            % Assemble the amplitude strengths
            F1 = 0.5 .* sinSq_theta_13_M .* sinSq_2theta_23;
            F2 = 0.5 .* cosSq_theta_13_M .* sinSq_2theta_23;
            F3 = 0.5 .* sinSq_2theta_13_M .* sinSq_theta_23.^2;
            
            % Assemble the Phases
            oscPhase1 = ((DELTA_m31_sq .* (1+A) - DELTA_Mm_Sq) .* distance .* 1.27) ./ Energy;
            oscPhase2 = ((DELTA_m31_sq .* (1+A) + DELTA_Mm_Sq) .* distance .* 1.27) ./ Energy;
            oscPhase3 = (DELTA_Mm_Sq .* distance .* 1.27 .* 2.0) ./ Energy;
            
            Probability =   1.0 - F1 .* (1.0 - cos(oscPhase1)) ...
                                - F2 .* (1.0 - exp(-GL) .* cos(oscPhase2)) ...
                                - F3 .* (1.0 - exp(-GL) .* cos(oscPhase3));
            
            type = app.TypeSwitch.Value;
            hierarchy = app.HierarchySwitch.Value;
            plotLegend = sprintf('%s Survival, %s', type, hierarchy);
            
            app.ParametersUsedinCalculationTextArea.Value = {...
                sprintf('theta_13 = %f degrees', rad2deg(theta_13)), ...
                sprintf('theta_23 = %f degrees', rad2deg(theta_23)), ...
                sprintf('theta_12 = %f degrees', rad2deg(theta_12)), ...
                sprintf('DELTA_m21_sq = %f', DELTA_m21_sq), ...
                sprintf('DELTA_m31_sq = %f', DELTA_m31_sq), ...
                sprintf('Ve = %f', Ve), ...
                sprintf('Density = %f', rho), ...
                sprintf('GL = %f', GL)};
        end
        
        function PlotMuonMuon(app, Probability, plot_legend)
            global Energy;
            
            % Modify Axes
            app.UIAxes.XLim = ([0.5, Energy(end)]);
            app.UIAxes.YLim = ([0, 1]);
            
            PlotTotalProbability(app, Probability, plot_legend);
        end
        
        function PlotMuonElectron(app, ProbabilityTerms, plot_legend)
            global Energy;
   
            % Modify Axes
            app.UIAxes.XLim = ([0.5, Energy(end)]);
            app.UIAxes.YLim = ([-0.02, 0.101]);
            
            if app.DecomposeButton.Value == 1
                PlotDecompose(app, ProbabilityTerms);
            else
                PlotTotalProbability(app, sum(ProbabilityTerms), plot_legend);
            end
        end
        
        function PlotDecompose(app, ProbabilityTerms)
            global Energy;
            
            hold(app.UIAxes, 'on')
            plot(Energy, ProbabilityTerms(1,:), 'Parent',app.UIAxes, 'DisplayName', sprintf('P0'));
            plot(Energy, ProbabilityTerms(2,:), 'Parent',app.UIAxes, 'DisplayName', sprintf('Pcos(\\delta)'));
            plot(Energy, ProbabilityTerms(3,:), 'Parent',app.UIAxes, 'DisplayName', sprintf('Psin(\\delta)'));
            plot(Energy, ProbabilityTerms(4,:), 'Parent',app.UIAxes, 'DisplayName', sprintf('P3'));
            hold(app.UIAxes, 'off')
        end
        
        function PlotTotalProbability(app, Probability, plot_legend)
            global Energy;
            
            plot(Energy, Probability,'Parent',app.UIAxes,'DisplayName', plot_legend);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % -------------------------------------------------------------
            % Modify Text with Special Characters
            % -------------------------------------------------------------
            app.DistancekmEditFieldLabel.Text = 'Distance [km]';
            app.Theta23EditFieldLabel.Text = [char(952), char(8322), char(8323), ' [degrees]'];
            app.Deltam31SqEditFieldLabel.Text = [char(916), 'm', char(178), char(8323), char(8321), ' [', '10', char(8315), char(179), ' eV', char(178),']'];
            app.DiracPhaseLabel.Text = ['Dirac Phase (', char(948), ')', ' [', char(960) ']' ];
            app.DecoherenceGammaEditFieldLabel.Text = ['Decoherence (', char(915), char(8320), ')'];
            app.Muon_ElectronButton.Text = ['Muon ', char(8594), ' Electron'];
            app.Muon_Electron_SMButton.Text = ['Muon ', char(8594), ' Electron', ' (SM)'];
            app.Muon_MuonButton.Text = ['Muon ', char(8594), ' Muon'];
            
            legend(app.UIAxes);
        end

        % Button pushed function: Muon_ElectronButton
        function Muon_ElectronButtonPushed(app, event)

            DeclareConstants(app);
            
            GetParametersFromGUI(app);
            
            [ProbabilityTerms, plot_legend] = CalculateMuonElectronProbability(app);
            
            PlotMuonElectron(app, ProbabilityTerms, plot_legend);
        end

        % Value changed function: HoldOnButton
        function HoldOnButtonValueChanged(app, event)
            if app.HoldOnButton.Value == 1
                hold(app.UIAxes, 'on')
            else
                hold(app.UIAxes, 'off')
            end
        end

        % Button pushed function: Muon_Electron_SMButton
        function Muon_Electron_SMButtonPushed(app, event)
            DeclareConstants(app);
            
            GetParametersFromGUI(app);
            
            [ProbabilityTerms, plot_legend] = CalculateMuonElectronProbabilitySM(app);

            PlotMuonElectron(app, ProbabilityTerms, plot_legend);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: Muon_MuonButton
        function Muon_MuonButtonPushed(app, event)
            DeclareConstants(app);
            
            GetParametersFromGUI(app);
            
            [Probability, plot_legend] = CalculateMuonMuonProbability(app);

            PlotMuonMuon(app, Probability, plot_legend);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Neutrino3flavorOscillationswithDecoherenceLabel = uilabel(app.UIFigure);
            app.Neutrino3flavorOscillationswithDecoherenceLabel.Text = 'Neutrino 3-flavor Oscillations with Decoherence';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Neutrino Energy (GeV)')
            ylabel(app.UIAxes, 'Probability')
            app.PlotProbabilityPanel = uipanel(app.UIFigure);
            app.PlotProbabilityPanel.Title = 'Plot Probability';
            app.Muon_ElectronButton = uibutton(app.PlotProbabilityPanel, 'push');
            app.Muon_ElectronButton.ButtonPushedFcn = createCallbackFcn(app, @Muon_ElectronButtonPushed, true);
            app.Muon_ElectronButton.Text = 'Muon_Electron';
            app.Muon_Electron_SMButton = uibutton(app.PlotProbabilityPanel, 'push');
            app.Muon_Electron_SMButton.ButtonPushedFcn = createCallbackFcn(app, @Muon_Electron_SMButtonPushed, true);
            app.Muon_Electron_SMButton.Text = 'Muon_Electron_SM';
            app.Muon_MuonButton = uibutton(app.PlotProbabilityPanel, 'push');
            app.Muon_MuonButton.ButtonPushedFcn = createCallbackFcn(app, @Muon_MuonButtonPushed, true);
            app.Muon_MuonButton.Text = 'Muon_Muon';
            app.ModifyParametersPanel = uipanel(app.UIFigure);
            app.ModifyParametersPanel.Title = 'Modify Parameters';
            app.DistancekmEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.DistancekmEditFieldLabel.Text = 'Distance (km)';
            app.DistancekmEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.DistancekmEditField.Limits = [100 12800];
            app.DistancekmEditField.Value = 810;
            app.DiracPhaseLabel = uilabel(app.ModifyParametersPanel);
            app.DiracPhaseLabel.Text = 'Dirac Phase ';
            app.DiracPhaseEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.Theta23EditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.Theta23EditFieldLabel.Text = 'Theta 23';
            app.Theta23EditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.Theta23EditField.Limits = [20 70];
            app.Theta23EditField.Value = 45;
            app.Deltam31SqEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.Deltam31SqEditFieldLabel.Text = 'Delta m31 Sq';
            app.Deltam31SqEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.Deltam31SqEditField.Limits = [2 3];
            app.Deltam31SqEditField.Value = 2.45;
            app.DecoherenceGammaEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.DecoherenceGammaEditFieldLabel.Text = 'Decoherence Gamma';
            app.DecoherenceGammaEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.DecoherenceGammaEditField.Limits = [0 5];
            app.DecoherenceGammaEditField.Value = 2.3;
            app.SelectModePanel = uipanel(app.UIFigure);
            app.SelectModePanel.Title = 'Select Mode';
            app.TypeSwitchLabel = uilabel(app.SelectModePanel);
            app.TypeSwitchLabel.Text = 'Type';
            app.TypeSwitch = uiswitch(app.SelectModePanel, 'slider');
            app.TypeSwitch.Items = {'Neutrino', 'Anti-Neutrino'};
            app.TypeSwitch.Value = 'Neutrino';
            app.HierarchySwitchLabel = uilabel(app.SelectModePanel);
            app.HierarchySwitchLabel.Text = 'Hierarchy';
            app.HierarchySwitch = uiswitch(app.SelectModePanel, 'slider');
            app.HierarchySwitch.Items = {'Normal', 'Inverted'};
            app.HierarchySwitch.Value = 'Normal';
            app.ParametersUsedinCalculationPanel = uipanel(app.UIFigure);
            app.ParametersUsedinCalculationPanel.Title = 'Parameters Used in Calculation';
            app.ParametersUsedinCalculationTextArea = uitextarea(app.ParametersUsedinCalculationPanel);
            app.SelectNeutrinoEnergyButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectNeutrinoEnergyButtonGroup.Title = 'Select Neutrino Energy';
            app.GeVButton = uiradiobutton(app.SelectNeutrinoEnergyButtonGroup);
            app.GeVButton.Text = '3 GeV';
            app.GeVButton_2 = uiradiobutton(app.SelectNeutrinoEnergyButtonGroup);
            app.GeVButton_2.Text = '5 GeV';
            app.GeVButton_3 = uiradiobutton(app.SelectNeutrinoEnergyButtonGroup);
            app.GeVButton_3.Text = '10 GeV';
            app.GeVButton_3.Value = true;
            app.SelectPlotOptionsPanel = uipanel(app.UIFigure);
            app.SelectPlotOptionsPanel.Title = 'Select Plot Options';
            app.DecomposeButton = uibutton(app.SelectPlotOptionsPanel, 'state');
            app.DecomposeButton.Text = 'Decompose';
            app.HoldOnButton = uibutton(app.SelectPlotOptionsPanel, 'state');
            app.HoldOnButton.ValueChangedFcn = createCallbackFcn(app, @HoldOnButtonValueChanged, true);
            app.HoldOnButton.Text = 'Hold On';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeutrinoOscillationsUltimate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end